classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Button     matlab.ui.control.Button
        Button_2   matlab.ui.control.Button
        Label      matlab.ui.control.Label
        Button_3   matlab.ui.control.Button
        LampLabel  matlab.ui.control.Label
        Lamp       matlab.ui.control.Lamp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global r;
            r = audiorecorder(44100,16,2);
            record(r);
            app.Lamp.Color = [0 1 0];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
%             global r;
%             play(r);
%             indir = 'C:\Users\emccz\Documents\录音\';
% filelist = getAllFilenames(indir); 
% filename = filelist(end).name;
% if(filename==".." || filename==".")
%     filename = filelist(end-2).name;
% end
% [alldata, f_s] = audioread([indir, filename]);
% [alldata, f_s] = audioread('C:\Users\emccz\Documents\录音\录音 (272).m4a');
global r;
alldata = getaudiodata(r);
f_s = 44100;
data = alldata(:,1);

window_len = 1024; % 窗长1024 约为23ms

cut_off_frequency = 3000;%?
decay = 0.1;
cut_off_start = round(cut_off_frequency / f_s * window_len) + 1;
cut_off_end = window_len + 1 - cut_off_start;
% window_overlap = 7000; % overlap 50%
window_overlap = 900; %  overlap 50%
% window_overlap = 450; % overlap 50%
t_end = length(alldata)/f_s; % 音频总时长
t = 0:1/f_s:t_end - 1/f_s; % 时间坐标轴
N = f_s * t_end; % 音频总采样点数
x_f = (0:f_s / N:f_s - f_s / N);
noise_start_time = 0.2;
noise_end_time = 0.7;
noise_start = noise_start_time * f_s + 1;
noise_end = noise_end_time * f_s;
noise_window_num = floor((noise_end - noise_start - window_len) / (window_len - window_overlap));
%噪音区间总窗数
noise_fft_power_sum = zeros(window_len, 1);
for ii = 1:noise_window_num
    frame_start = noise_start + (ii - 1) * (window_len - window_overlap);
    frame_end = noise_start + (ii - 1) * (window_len - window_overlap) + window_len - 1;
    frame_data = data(frame_start:frame_end);
    % 加窗
    frame_data = frame_data .* hamming(window_len);
    frame_fft = fft(frame_data);
%     frame_fft(cut_off_start:cut_off_end) = 0;
    frame_fft(cut_off_start:cut_off_end) = frame_fft(cut_off_start:cut_off_end) * decay;
    frame_fft_power = abs(frame_fft).^2;
    noise_fft_power_sum = noise_fft_power_sum + frame_fft_power;
end
noise_fft_power_sum = noise_fft_power_sum / noise_window_num;
% figure;
% plot(noise_fft_power_sum);
% 开始降噪
filter_alpha =2;%过大或过小都会使噪声很大
filter_beta = 0.2;%越小噪声越小，但是语音失真明显
output_frame_num = floor((N - noise_end - window_len) / (window_len - window_overlap));
data_ifft = zeros(output_frame_num * (window_len - window_overlap) + window_len, 1);
for ii = 1:output_frame_num
    frame_start = noise_end + 1 + (ii - 1) * (window_len - window_overlap);
    frame_end = noise_end + (ii - 1) * (window_len - window_overlap) + window_len;
    frame_data = data(frame_start:frame_end);
    % 加窗
    frame_data = frame_data .* hamming(window_len);
    frame_fft = fft(frame_data);
%     frame_fft(cut_off_start:cut_off_end) = 0;
    frame_fft(cut_off_start:cut_off_end) = frame_fft(cut_off_start:cut_off_end) * decay;
    frame_fft_power = abs(frame_fft).^2;
    frame_fft_angle = angle(frame_fft);

    frame_fft_power_output = max(frame_fft_power - filter_alpha * noise_fft_power_sum, filter_beta * frame_fft_power);
    %?

    frame_fft_output = sqrt(frame_fft_power_output) .* (cos(frame_fft_angle) + 1i * sin(frame_fft_angle));
    frame_ifft = ifft(frame_fft_output);
    data_ifft((ii - 1) * (window_len - window_overlap) + 1:(ii - 1) * (window_len - window_overlap) + window_len) = ...
    data_ifft((ii - 1) * (window_len - window_overlap) + 1:(ii - 1) * (window_len - window_overlap) + window_len) + frame_ifft;
end
data_output = real(data_ifft) / max(real(data_ifft));
outputtt=WienerScalart96m_2(alldata,f_s,.15,0.03);
t_end1 = length(outputtt)/f_s; % 音频总时长
t1 = 0:1/f_s:t_end1 - 1/f_s; % 时间坐标轴
x = alldata;
x = x - mean(x);
x_fft = fft(x);
x_f = (0:f_s / N:f_s - f_s / N) - f_s / 2;
x_f_real = (0:f_s / N:f_s - f_s / N);
x_fft_shift = abs(fftshift(x_fft));
x_fft_abs = abs(x_fft);

%% 
audiowrite('维纳滤波法.m4a',outputtt,f_s);
sound(outputtt, f_s);

function [dirOutput] = getAllFilenames(filePattern)
% Function: Get all file information (filenames) of the same directory, and sort by date.
    dirOutput = dir(filePattern);
    [~, ind] = sort([dirOutput(:).datenum], 'ascend');
    dirOutput = dirOutput(ind);
end


        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            global r;
            stop(r);
            app.Lamp.Color = [1 0 0];            

        end

        % Callback function
        function Button_4Pushed(app, event)
            [data, fs] = audioread("good.m4a");
            sound(data, fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始录音';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '播放硬盘录音';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '硬盘窃听演示';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '停止录音';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = '状态';
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end